classdef bh_car_envelope_calcs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SPEEDTab                        matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        RADIUSTab                       matlab.ui.container.Tab
        WheelRadiuscmSliderLabel        matlab.ui.control.Label
        SP_RAD_WheelRadiuscmSlider      matlab.ui.control.Slider
        UNITSPanel                      matlab.ui.container.Panel
        SP_RAD_RotationalspeedKnob_rot  matlab.ui.control.DiscreteKnob
        SP_RAD_RotationalspeedKnob_trans  matlab.ui.control.DiscreteKnob
        PLOTSPanel_2                    matlab.ui.container.Panel
        SP_RAD_UIAxes                   matlab.ui.control.UIAxes
        SP_RAD_doplotButton             matlab.ui.control.Button
        LIMITSPanel                     matlab.ui.container.Panel
        kmhourSliderLabel               matlab.ui.control.Label
        SP_RAD_kmhourSlider             matlab.ui.control.Slider
        ROLL_RESISTTab                  matlab.ui.container.Tab
        ConfigPanel                     matlab.ui.container.Panel
        NumberofwheelsKnobLabel         matlab.ui.control.Label
        SP_RR_NumberofwheelsKnob        matlab.ui.control.DiscreteKnob
        CarmasskgKnobLabel              matlab.ui.control.Label
        SP_RR_CarmasskgKnob             matlab.ui.control.Knob
        PLOTSPanel                      matlab.ui.container.Panel
        TabGroup3                       matlab.ui.container.TabGroup
        SIMPLETab                       matlab.ui.container.Tab
        SP_RR_SIMP_UIAxes               matlab.ui.control.UIAxes
        SP_RR_SIMP_doplotButton         matlab.ui.control.Button
        TstopsecsDropDownLabel          matlab.ui.control.Label
        SP_RR_SIMP_TstopsecsDropDown    matlab.ui.control.DropDown
        VmaxcmsecLabel                  matlab.ui.control.Label
        SP_RR_SIMP_VmaxDropDown         matlab.ui.control.DropDown
        AEROTab                         matlab.ui.container.Tab
        ACCELTab                        matlab.ui.container.Tab
        ConfigPanel_3                   matlab.ui.container.Panel
        NumberofmotorsLabel             matlab.ui.control.Label
        SP_ACC_NumberofMotorsKnob       matlab.ui.control.DiscreteKnob
        CarmasskgKnobLabel_2            matlab.ui.control.Label
        SP_ACC_CarmasskgKnob            matlab.ui.control.Knob
        WheelradiuscmSlider_2Label      matlab.ui.control.Label
        SP_ACC_WheelradiuscmSlider      matlab.ui.control.Slider
        PLOTSPanel_3                    matlab.ui.container.Panel
        VmaxcmsecLabel_2                matlab.ui.control.Label
        SP_ACC_VmaxDropDown             matlab.ui.control.DropDown
        TgoalsecsLabel                  matlab.ui.control.Label
        SP_ACC_TgoalsecsDropDown        matlab.ui.control.DropDown
        SP_ACC_doplotButton             matlab.ui.control.Button
        SP_ACC_UIAxes                   matlab.ui.control.UIAxes
        INERTIATab                      matlab.ui.container.Tab
        ConfigPanel_2                   matlab.ui.container.Panel
        WheelradiuscmSliderLabel        matlab.ui.control.Label
        WheelradiuscmSlider             matlab.ui.control.Slider
        WheelthicknesscmSliderLabel     matlab.ui.control.Label
        WheelthicknesscmSlider          matlab.ui.control.Slider
        WheelmassgramsSliderLabel       matlab.ui.control.Label
        WheelmassgramsSlider            matlab.ui.control.Slider
        Panel                           matlab.ui.container.Panel
    end

    methods (Access = private)

        % Button pushed function: SP_RAD_doplotButton
        function SP_RAD_doplotButtonPushed(app, event)
            inp_S.wheel_rad_cm         = app.SP_RAD_WheelRadiuscmSlider.Value;
            inp_S.trans_limit_kmper_hr = app.SP_RAD_kmhourSlider.Value;
            inp_S.units_rotat_str      = app.SP_RAD_RotationalspeedKnob_rot.Value;
            inp_S.units_trans_str      = app.SP_RAD_RotationalspeedKnob_trans.Value;
            
            hax = app.SP_RAD_UIAxes;
            % convert UNIT strings to 
            OBJ = bh_exp_speed_radius_CLS( inp_S );
            OBJ.do_plot(hax);            
        end

        % Button pushed function: SP_RR_SIMP_doplotButton
        function SP_RR_SIMP_doplotButtonPushed(app, event)
            NUM_WHEELS      = str2num(app.SP_RR_NumberofwheelsKnob.Value);
            mass_car_kg     = app.SP_RR_CarmasskgKnob.Value ;
            Tstop           = str2num(app.SP_RR_SIMP_TstopsecsDropDown.Value);
            Vmax_cm_per_sec = str2num(app.SP_RR_SIMP_VmaxDropDown.Value);            
            
            hax = app.SP_RR_SIMP_UIAxes;
            
            OBJ = bh_exp_roll_resist_CLS();
            
            OBJ.do_const_deceleration(hax, NUM_WHEELS, mass_car_kg, Tstop, Vmax_cm_per_sec);
        end

        % Button pushed function: SP_ACC_doplotButton
        function SP_ACC_doplotButtonPushed(app, event)
            NUM_MOTORS      = str2num(app.SP_ACC_NumberofMotorsKnob.Value);
            mass_car_kg     = app.SP_ACC_CarmasskgKnob.Value ;
            Tgoal           = str2num(app.SP_ACC_TgoalsecsDropDown.Value);
            Vmax_cm_per_sec = str2num(app.SP_ACC_VmaxDropDown.Value);
            wheel_rad_cm    = app.SP_ACC_WheelradiuscmSlider.Value;
            
            hax = app.SP_ACC_UIAxes;
            
            OBJ = bh_exp_accel_CLS();
            
            OBJ.do_const_acceleration(hax, NUM_MOTORS, mass_car_kg, wheel_rad_cm, Tgoal, Vmax_cm_per_sec);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SPEEDTab = uitab(app.TabGroup);
            app.SPEEDTab.Title = 'SPEED';
            app.TabGroup2 = uitabgroup(app.SPEEDTab);
            app.RADIUSTab = uitab(app.TabGroup2);
            app.RADIUSTab.Title = 'RADIUS';
            app.WheelRadiuscmSliderLabel = uilabel(app.RADIUSTab);
            app.WheelRadiuscmSliderLabel.Text = 'Wheel Radius (cm)';
            app.SP_RAD_WheelRadiuscmSlider = uislider(app.RADIUSTab);
            app.SP_RAD_WheelRadiuscmSlider.Limits = [1 10];
            app.SP_RAD_WheelRadiuscmSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.SP_RAD_WheelRadiuscmSlider.Value = 2.54;
            app.UNITSPanel = uipanel(app.RADIUSTab);
            app.UNITSPanel.Title = 'UNITS';
            app.SP_RAD_RotationalspeedKnob_rot = uiknob(app.UNITSPanel, 'discrete');
            app.SP_RAD_RotationalspeedKnob_rot.Items = {'rad_per_sec', 'RPS', 'RPM', 'deg_per_sec'};
            app.SP_RAD_RotationalspeedKnob_rot.Value = 'rad_per_sec';
            app.SP_RAD_RotationalspeedKnob_trans = uiknob(app.UNITSPanel, 'discrete');
            app.SP_RAD_RotationalspeedKnob_trans.Items = {'m_per_sec', 'cm_per_sec', 'km_per_hr'};
            app.SP_RAD_RotationalspeedKnob_trans.Value = 'm_per_sec';
            app.PLOTSPanel_2 = uipanel(app.RADIUSTab);
            app.PLOTSPanel_2.Title = 'PLOTS';
            app.SP_RAD_UIAxes = uiaxes(app.PLOTSPanel_2);
            title(app.SP_RAD_UIAxes, 'Rotational vs Translational velocity')
            xlabel(app.SP_RAD_UIAxes, 'ROTATIONAL')
            ylabel(app.SP_RAD_UIAxes, 'TRANS')
            app.SP_RAD_doplotButton = uibutton(app.PLOTSPanel_2, 'push');
            app.SP_RAD_doplotButton.ButtonPushedFcn = createCallbackFcn(app, @SP_RAD_doplotButtonPushed, true);
            app.SP_RAD_doplotButton.Text = 'do plot';
            app.LIMITSPanel = uipanel(app.RADIUSTab);
            app.LIMITSPanel.Title = 'LIMITS:';
            app.kmhourSliderLabel = uilabel(app.LIMITSPanel);
            app.kmhourSliderLabel.Text = 'km/hour';
            app.SP_RAD_kmhourSlider = uislider(app.LIMITSPanel);
            app.SP_RAD_kmhourSlider.Limits = [0 60];
            app.SP_RAD_kmhourSlider.MinorTicks = [];
            app.SP_RAD_kmhourSlider.Value = 0.5;
            app.ROLL_RESISTTab = uitab(app.TabGroup2);
            app.ROLL_RESISTTab.Title = 'ROLL_RESIST';
            app.ConfigPanel = uipanel(app.ROLL_RESISTTab);
            app.ConfigPanel.Title = 'Config';
            app.NumberofwheelsKnobLabel = uilabel(app.ConfigPanel);
            app.NumberofwheelsKnobLabel.Text = 'Number of wheels';
            app.SP_RR_NumberofwheelsKnob = uiknob(app.ConfigPanel, 'discrete');
            app.SP_RR_NumberofwheelsKnob.Items = {'4', '2', '3', '1'};
            app.SP_RR_NumberofwheelsKnob.Value = '4';
            app.CarmasskgKnobLabel = uilabel(app.ConfigPanel);
            app.CarmasskgKnobLabel.Text = 'Car mass (kg)';
            app.SP_RR_CarmasskgKnob = uiknob(app.ConfigPanel, 'continuous');
            app.SP_RR_CarmasskgKnob.Limits = [0.5 10];
            app.SP_RR_CarmasskgKnob.MajorTicks = [0.5 1 1.5 2 2.5 3 3.5 4 4.5 5 5.5 6 6.5 7 7.5 8 8.5 9 9.5 10];
            app.SP_RR_CarmasskgKnob.MinorTicks = [];
            app.SP_RR_CarmasskgKnob.Value = 1.5;
            app.PLOTSPanel = uipanel(app.ROLL_RESISTTab);
            app.PLOTSPanel.Title = 'PLOTS';
            app.TabGroup3 = uitabgroup(app.PLOTSPanel);
            app.SIMPLETab = uitab(app.TabGroup3);
            app.SIMPLETab.Title = 'SIMPLE';
            app.SP_RR_SIMP_UIAxes = uiaxes(app.SIMPLETab);
            title(app.SP_RR_SIMP_UIAxes, 'Rolling Resistance FORCE  per wheel ')
            xlabel(app.SP_RR_SIMP_UIAxes, 'Time (secs)')
            ylabel(app.SP_RR_SIMP_UIAxes, 'V0  (cm/sec)')
            app.SP_RR_SIMP_doplotButton = uibutton(app.SIMPLETab, 'push');
            app.SP_RR_SIMP_doplotButton.ButtonPushedFcn = createCallbackFcn(app, @SP_RR_SIMP_doplotButtonPushed, true);
            app.SP_RR_SIMP_doplotButton.Text = 'do plot';
            app.TstopsecsDropDownLabel = uilabel(app.SIMPLETab);
            app.TstopsecsDropDownLabel.Text = 'Tstop (secs)';
            app.SP_RR_SIMP_TstopsecsDropDown = uidropdown(app.SIMPLETab);
            app.SP_RR_SIMP_TstopsecsDropDown.Items = {'1', '5', '10', '20'};
            app.SP_RR_SIMP_TstopsecsDropDown.Value = '1';
            app.VmaxcmsecLabel = uilabel(app.SIMPLETab);
            app.VmaxcmsecLabel.Text = ' Vmax ( cm / sec)';
            app.SP_RR_SIMP_VmaxDropDown = uidropdown(app.SIMPLETab);
            app.SP_RR_SIMP_VmaxDropDown.Items = {'1', '5', '10', '20', '40', '60', '80', '100', '150'};
            app.SP_RR_SIMP_VmaxDropDown.Value = '10';
            app.AEROTab = uitab(app.TabGroup3);
            app.AEROTab.Title = 'AERO';
            app.ACCELTab = uitab(app.TabGroup2);
            app.ACCELTab.Title = 'ACCEL';
            app.ConfigPanel_3 = uipanel(app.ACCELTab);
            app.ConfigPanel_3.Title = 'Config';
            app.NumberofmotorsLabel = uilabel(app.ConfigPanel_3);
            app.NumberofmotorsLabel.Text = 'Number of motors';
            app.SP_ACC_NumberofMotorsKnob = uiknob(app.ConfigPanel_3, 'discrete');
            app.SP_ACC_NumberofMotorsKnob.Items = {'2', '4'};
            app.SP_ACC_NumberofMotorsKnob.Value = '2';
            app.CarmasskgKnobLabel_2 = uilabel(app.ConfigPanel_3);
            app.CarmasskgKnobLabel_2.Text = 'Car mass (kg)';
            app.SP_ACC_CarmasskgKnob = uiknob(app.ConfigPanel_3, 'continuous');
            app.SP_ACC_CarmasskgKnob.Limits = [0.5 5];
            app.SP_ACC_CarmasskgKnob.MinorTicks = [];
            app.SP_ACC_CarmasskgKnob.Value = 1.5;
            app.WheelradiuscmSlider_2Label = uilabel(app.ConfigPanel_3);
            app.WheelradiuscmSlider_2Label.Text = 'Wheel radius (cm)';
            app.SP_ACC_WheelradiuscmSlider = uislider(app.ConfigPanel_3);
            app.SP_ACC_WheelradiuscmSlider.Limits = [1 6];
            app.SP_ACC_WheelradiuscmSlider.MajorTicks = [1 2 3 4 5 6];
            app.SP_ACC_WheelradiuscmSlider.Value = 2.5;
            app.PLOTSPanel_3 = uipanel(app.ACCELTab);
            app.PLOTSPanel_3.Title = 'PLOTS';
            app.VmaxcmsecLabel_2 = uilabel(app.PLOTSPanel_3);
            app.VmaxcmsecLabel_2.Text = ' Vmax ( cm / sec)';
            app.SP_ACC_VmaxDropDown = uidropdown(app.PLOTSPanel_3);
            app.SP_ACC_VmaxDropDown.Items = {'1', '5', '10', '20', '40', '60', '80', '100', '150'};
            app.SP_ACC_VmaxDropDown.Value = '10';
            app.TgoalsecsLabel = uilabel(app.PLOTSPanel_3);
            app.TgoalsecsLabel.Text = 'Tgoal (secs)';
            app.SP_ACC_TgoalsecsDropDown = uidropdown(app.PLOTSPanel_3);
            app.SP_ACC_TgoalsecsDropDown.Items = {'1', '0.1', '0.05', '0.01', '5'};
            app.SP_ACC_TgoalsecsDropDown.Value = '1';
            app.SP_ACC_doplotButton = uibutton(app.PLOTSPanel_3, 'push');
            app.SP_ACC_doplotButton.ButtonPushedFcn = createCallbackFcn(app, @SP_ACC_doplotButtonPushed, true);
            app.SP_ACC_doplotButton.Text = 'do plot';
            app.SP_ACC_UIAxes = uiaxes(app.PLOTSPanel_3);
            title(app.SP_ACC_UIAxes, 'Torque per motor')
            xlabel(app.SP_ACC_UIAxes, 'Time (secs)')
            ylabel(app.SP_ACC_UIAxes, 'V  (cm/sec)')
            app.INERTIATab = uitab(app.TabGroup);
            app.INERTIATab.Title = 'INERTIA';
            app.ConfigPanel_2 = uipanel(app.INERTIATab);
            app.ConfigPanel_2.Title = 'Config';
            app.WheelradiuscmSliderLabel = uilabel(app.ConfigPanel_2);
            app.WheelradiuscmSliderLabel.Text = 'Wheel radius (cm)';
            app.WheelradiuscmSlider = uislider(app.ConfigPanel_2);
            app.WheelradiuscmSlider.Limits = [1 10];
            app.WheelradiuscmSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.WheelradiuscmSlider.Value = 1;
            app.WheelthicknesscmSliderLabel = uilabel(app.ConfigPanel_2);
            app.WheelthicknesscmSliderLabel.Text = 'Wheel thickness(cm)';
            app.WheelthicknesscmSlider = uislider(app.ConfigPanel_2);
            app.WheelthicknesscmSlider.Limits = [1 10];
            app.WheelthicknesscmSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.WheelthicknesscmSlider.Value = 1;
            app.WheelmassgramsSliderLabel = uilabel(app.ConfigPanel_2);
            app.WheelmassgramsSliderLabel.Text = 'Wheel mass (grams)';
            app.WheelmassgramsSlider = uislider(app.ConfigPanel_2);
            app.WheelmassgramsSlider.Limits = [10 200];
            app.WheelmassgramsSlider.MajorTicks = [10 50 90 130 200];
            app.WheelmassgramsSlider.Value = 10;
            app.Panel = uipanel(app.INERTIATab);
            app.Panel.Title = 'Panel';
        end
    end

    methods (Access = public)

        % Construct app
        function app = bh_car_envelope_calcs

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end