classdef bh_DRC_demo_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DRCDEMOUIFigure             matlab.ui.Figure
        DRCDEMOselectorLabel        matlab.ui.control.Label
        TabGroup                    matlab.ui.container.TabGroup
        HELLOTab                    matlab.ui.container.Tab
        TextArea_2                  matlab.ui.control.TextArea
        HelloandwelcomeLabel        matlab.ui.control.Label
        Hello_BUT                   matlab.ui.control.Button
        HELLO_AX_01                 matlab.ui.control.UIAxes
        Hello_BUT_PRES              matlab.ui.control.Button
        Car_ModelTab                matlab.ui.container.Tab
        Knob_Car_Model              matlab.ui.control.DiscreteKnob
        DEMOSLabel                  matlab.ui.control.Label
        Comp_VisTab                 matlab.ui.container.Tab
        Knob_Comp_Vision            matlab.ui.control.DiscreteKnob
        DEMOSLabel_2                matlab.ui.control.Label
        ViewersTab                  matlab.ui.container.Tab
        Knob_Viewers_CAR            matlab.ui.control.DiscreteKnob
        Knob_Viewers_ARENA          matlab.ui.control.DiscreteKnob
        CARLabel                    matlab.ui.control.Label
        ArenaLabel                  matlab.ui.control.Label
        andLabel                    matlab.ui.control.Label
        CAMERALabel                 matlab.ui.control.Label
        Filter_KFTab                matlab.ui.container.Tab
        Knob_Filter                 matlab.ui.control.DiscreteKnob
        DEMOSLabel_3                matlab.ui.control.Label
        Code_genTab                 matlab.ui.container.Tab
        Knob_CODEGEN                matlab.ui.control.DiscreteKnob
        DEMOSLabel_4                matlab.ui.control.Label
        HELPTab                     matlab.ui.container.Tab
        TextArea                    matlab.ui.control.TextArea
        DemoGUIDEandcommentsButton  matlab.ui.control.Button
        HELP_AX                     matlab.ui.control.UIAxes
        DRCCompetitionSlideButton   matlab.ui.control.Button
    end


    properties (Access = private)
        OBJ_bh_DS 
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.OBJ_bh_DS = bh_DRC_demo_selector_CLS();
            
            % add a graphic to the HELLO tab sheet
            hax = app.HELLO_AX_01;
            I = imread('bh_graphic_01.JPG');  %bh_graphic_01.JPG  bh_CAR_geo_06.JPG
            %imshow(I,'Parent', hax);
            image(hax,I);
            axis(hax,'tight');
            hax.Visible = 'off';
            
            % and add the graphic to the HELP tab sheet
            hax = app.HELP_AX;
            I = imread('bh_CAR_geo_06.JPG');
            image(hax,I);
            axis(hax,'tight');
            hax.Visible = 'off';            
        end

        % Value changed function: Knob_Car_Model
        function Knob_Car_ModelValueChanged(app, event)
            value = app.Knob_Car_Model.Value;
            
             switch(upper(value))
                case {'OFF'}
                        % do NOTHING
                case {'DERIVE_3DOF'}
                        app.OBJ_bh_DS.task_car_derivation();
                case {'MODEL_EOM'}
                        app.OBJ_bh_DS.task_car_model_eom();
                case {'MODEL_PARAMETERS'}
                        app.OBJ_bh_DS.task_car_model_parameters();     
                case {'MODEL_SIMULATE'}
                        app.OBJ_bh_DS.task_car_model_simulate();                             
                case {'MODEL_EVASSIVE_MANS'}
                        app.OBJ_bh_DS.task_car_model_evassive_mans();                            
                case {'AUTOMATE_EVASSIVE_MANS'}
                        app.OBJ_bh_DS.task_car_automate_evassive_mans();   
                otherwise                  
                    error('###_ERROR: UNknown DEMO wheel choice !');
            end                       
        end

        % Value changed function: Knob_Comp_Vision
        function Knob_Comp_VisionValueChanged(app, event)
            value = app.Knob_Comp_Vision.Value;
            
             switch(upper(value))
                case {'OFF'}
                        % do NOTHING
                case {'NN_CONCEPT'}
                        app.OBJ_bh_DS.task_cv_nn_concept();
                case {'NN_PROTOTYPE'}
                        app.OBJ_bh_DS.task_cv_nn_prototype();
                case {'NN_CODE_GENERATION'}
                        app.OBJ_bh_DS.task_cv_nn_codegen();                
                case {'NN_SYSTEM_MODEL'}
                        app.OBJ_bh_DS.task_cv_nn_system_model();                        

                otherwise                  
                    error('###_ERROR: UNknown DEMO wheel choice !');
            end                                   
            
        end

        % Value changed function: Knob_Viewers_CAR
        function Knob_Viewers_CARValueChanged(app, event)
            value = app.Knob_Viewers_CAR.Value;
            
           switch(upper(value))
                case {'OFF'}
                        % do NOTHING
                case {'CONCEPT'}
                        app.OBJ_bh_DS.task_viewers_car_concept();
                case {'SL_VIEW'}
                        app.OBJ_bh_DS.task_viewers_car_SL();
                otherwise                  
                    error('###_ERROR: UNknown DEMO wheel choice !');
            end                        
            
        end

        % Value changed function: Knob_Viewers_ARENA
        function Knob_Viewers_ARENAValueChanged(app, event)
            value = app.Knob_Viewers_ARENA.Value;
            
           switch(upper(value))
                case {'OFF'}
                        % do NOTHING
                case {'ARENA'}
                        app.OBJ_bh_DS.task_viewers_arena_concept();
                case {'CAMERA'}
                        app.OBJ_bh_DS.task_viewers_camera_concept();                                              
                case {'SL_VIEW'}
                        app.OBJ_bh_DS.task_viewers_camera_SL();
                otherwise                  
                    error('###_ERROR: UNknown DEMO wheel choice !');
            end                        
                        
        end

        % Value changed function: Knob_Filter
        function Knob_FilterValueChanged(app, event)
            value = app.Knob_Filter.Value;
            
           switch(upper(value))
                case {'OFF'}
                        % do NOTHING
                case {'CONCEPT_KF_LINEAR'}
                        app.OBJ_bh_DS.task_filter_kf_concept();
                case {'BUT_THIS_IS_A_ROUND_WHEEL'}
                        app.OBJ_bh_DS.task_filter_kf_ROUND_wheel();                                              
                otherwise                  
                    error('###_ERROR: UNknown DEMO wheel choice !');
            end                       
            
        end

        % Value changed function: Knob_CODEGEN
        function Knob_CODEGENValueChanged(app, event)
            value = app.Knob_CODEGEN.Value;

            switch(upper(value))
                case {'OFF'}
                        % do NOTHING
                case {'OUR_ALGORITHM'}
                        app.OBJ_bh_DS.task_codegen_algorithm();
                case {'USING_CODEGEN'}
                        app.OBJ_bh_DS.task_codegen_using_cg();  
                case {'USING_THE_APP'}
                        app.OBJ_bh_DS.task_codegen_using_app();                          
                case {'DOC_GETTING_STARTED'}
                        app.OBJ_bh_DS.task_codegen_getting_started();                               
                case {'DOC_USE_WITH_YOUR_CODE'}
                        app.OBJ_bh_DS.task_codegen_using_inside_your_software();                             
                        
                otherwise                  
                    error('###_ERROR: UNknown DEMO wheel choice !');
            end                     
        end

        % Button pushed function: Hello_BUT
        function Hello_BUTButtonPushed(app, event)
            app.OBJ_bh_DS.task_button_play_movie("TEASER");
        end

        % Button pushed function: Hello_BUT_PRES
        function Hello_BUT_PRESButtonPushed(app, event)
            app.OBJ_bh_DS.open_pres();
        end

        % Button pushed function: DemoGUIDEandcommentsButton
        function DemoGUIDEandcommentsButtonPushed(app, event)
            app.OBJ_bh_DS.open_pres();
        end

        % Button pushed function: DRCCompetitionSlideButton
        function DRCCompetitionSlideButtonPushed(app, event)
            app.OBJ_bh_DS.open_DRC_SLIDE();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DRCDEMOUIFigure = uifigure;
            app.DRCDEMOUIFigure.Name = 'DRC DEMO';
            app.DRCDEMOselectorLabel = uilabel(app.DRCDEMOUIFigure);
            app.DRCDEMOselectorLabel.Text = 'DRC DEMO selector';
            app.TabGroup = uitabgroup(app.DRCDEMOUIFigure);
            app.HELLOTab = uitab(app.TabGroup);
            app.HELLOTab.Title = 'HELLO';
            app.TextArea_2 = uitextarea(app.HELLOTab);
            app.TextArea_2.Value = {'In this demo we''re going to develop a vision based control system for controlling an autonomous ground vehicle.'; ''; 'Specifically we''ll look at the following tasks:'; ''; '1.) creating a dynamic model for a car'; '2.) exploring a "lane change" evassive manoeuvre.'; '3.) prototyping a lane and obstacle detection vision algorithm'; '4.) developing a virtual world and camera'; '5.) simulating the complete vehicle,and vision SYSTEM model'; ''; 'For a teaser, click the button below for a short video showing our final SYSTEM model.'; ''; 'Also, check out the COMPANION document that gives some SHORT commentary on the demo.'};
            app.HelloandwelcomeLabel = uilabel(app.HELLOTab);
            app.HelloandwelcomeLabel.Text = 'Hello and welcome.';
            app.Hello_BUT = uibutton(app.HELLOTab, 'push');
            app.Hello_BUT.ButtonPushedFcn = createCallbackFcn(app, @Hello_BUTButtonPushed, true);
            app.Hello_BUT.Text = 'A video preview on what we''ll cover';
            app.HELLO_AX_01 = uiaxes(app.HELLOTab);
            app.Hello_BUT_PRES = uibutton(app.HELLOTab, 'push');
            app.Hello_BUT_PRES.ButtonPushedFcn = createCallbackFcn(app, @Hello_BUT_PRESButtonPushed, true);
            app.Hello_BUT_PRES.Text = 'A guide/companion for the DEMO';
            app.Car_ModelTab = uitab(app.TabGroup);
            app.Car_ModelTab.Title = 'Car_Model';
            app.Knob_Car_Model = uiknob(app.Car_ModelTab, 'discrete');
            app.Knob_Car_Model.Items = {'OFF', 'Derive_3DOF', 'Model_Eom', 'Model_parameters', 'Model_simulate', 'Model_evassive_mans', 'Automate_evassive_mans'};
            app.Knob_Car_Model.ValueChangedFcn = createCallbackFcn(app, @Knob_Car_ModelValueChanged, true);
            app.Knob_Car_Model.Value = 'OFF';
            app.DEMOSLabel = uilabel(app.Car_ModelTab);
            app.DEMOSLabel.Text = 'DEMOS';
            app.Comp_VisTab = uitab(app.TabGroup);
            app.Comp_VisTab.Title = 'Comp_Vis';
            app.Knob_Comp_Vision = uiknob(app.Comp_VisTab, 'discrete');
            app.Knob_Comp_Vision.Items = {'OFF', 'NN_concept', 'NN_Prototype', 'NN_Code_generation', 'NN_System_model'};
            app.Knob_Comp_Vision.ValueChangedFcn = createCallbackFcn(app, @Knob_Comp_VisionValueChanged, true);
            app.Knob_Comp_Vision.Value = 'OFF';
            app.DEMOSLabel_2 = uilabel(app.Comp_VisTab);
            app.DEMOSLabel_2.Text = 'DEMOS';
            app.ViewersTab = uitab(app.TabGroup);
            app.ViewersTab.Title = 'Viewers';
            app.Knob_Viewers_CAR = uiknob(app.ViewersTab, 'discrete');
            app.Knob_Viewers_CAR.Items = {'OFF', 'concept', 'SL_view'};
            app.Knob_Viewers_CAR.ValueChangedFcn = createCallbackFcn(app, @Knob_Viewers_CARValueChanged, true);
            app.Knob_Viewers_CAR.Value = 'OFF';
            app.Knob_Viewers_ARENA = uiknob(app.ViewersTab, 'discrete');
            app.Knob_Viewers_ARENA.Items = {'OFF', 'arena', 'camera', 'SL_view'};
            app.Knob_Viewers_ARENA.ValueChangedFcn = createCallbackFcn(app, @Knob_Viewers_ARENAValueChanged, true);
            app.Knob_Viewers_ARENA.Value = 'OFF';
            app.CARLabel = uilabel(app.ViewersTab);
            app.CARLabel.Text = 'CAR';
            app.ArenaLabel = uilabel(app.ViewersTab);
            app.ArenaLabel.Text = 'Arena ';
            app.andLabel = uilabel(app.ViewersTab);
            app.andLabel.Text = 'and ';
            app.CAMERALabel = uilabel(app.ViewersTab);
            app.CAMERALabel.Text = 'CAMERA';
            app.Filter_KFTab = uitab(app.TabGroup);
            app.Filter_KFTab.Title = 'Filter_KF';
            app.Knob_Filter = uiknob(app.Filter_KFTab, 'discrete');
            app.Knob_Filter.Items = {'OFF', 'concept_KF_Linear', 'BUT_this_is_a_ROUND_wheel'};
            app.Knob_Filter.ValueChangedFcn = createCallbackFcn(app, @Knob_FilterValueChanged, true);
            app.Knob_Filter.Value = 'OFF';
            app.DEMOSLabel_3 = uilabel(app.Filter_KFTab);
            app.DEMOSLabel_3.Text = 'DEMOS';
            app.Code_genTab = uitab(app.TabGroup);
            app.Code_genTab.Title = 'Code_gen';
            app.Knob_CODEGEN = uiknob(app.Code_genTab, 'discrete');
            app.Knob_CODEGEN.Items = {'OFF', 'Our_algorithm', 'Using_CODEGEN', 'Using_the_APP', 'DOC_Getting_started', 'DOC_USE_WITH_YOUR_CODE'};
            app.Knob_CODEGEN.ValueChangedFcn = createCallbackFcn(app, @Knob_CODEGENValueChanged, true);
            app.Knob_CODEGEN.Value = 'OFF';
            app.DEMOSLabel_4 = uilabel(app.Code_genTab);
            app.DEMOSLabel_4.Text = 'DEMOS';
            app.HELPTab = uitab(app.TabGroup);
            app.HELPTab.Title = 'HELP';
            app.TextArea = uitextarea(app.HELPTab);
            app.TextArea.Value = {'Click the button below for a quick guide on how to use this demo !'};
            app.DemoGUIDEandcommentsButton = uibutton(app.HELPTab, 'push');
            app.DemoGUIDEandcommentsButton.ButtonPushedFcn = createCallbackFcn(app, @DemoGUIDEandcommentsButtonPushed, true);
            app.DemoGUIDEandcommentsButton.Text = 'Demo GUIDE and comments';
            app.HELP_AX = uiaxes(app.HELPTab);
            app.DRCCompetitionSlideButton = uibutton(app.HELPTab, 'push');
            app.DRCCompetitionSlideButton.ButtonPushedFcn = createCallbackFcn(app, @DRCCompetitionSlideButtonPushed, true);
            app.DRCCompetitionSlideButton.Text = 'DRC Competition Slide';
        end
    end

    methods (Access = public)

        % Construct app
        function app = bh_DRC_demo_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DRCDEMOUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DRCDEMOUIFigure)
        end
    end
end