classdef bh_solenoid_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SOLENOIDDEMOUIFigure            matlab.ui.Figure
        SolenoidModellingDEMOselectorLabel  matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        HELLOTab                        matlab.ui.container.Tab
        TextArea                        matlab.ui.control.TextArea
        HelloandwelcomeLabel            matlab.ui.control.Label
        HELLO_AX                        matlab.ui.control.UIAxes
        OpenPresentationButton          matlab.ui.control.Button
        DATATab                         matlab.ui.container.Tab
        ModelParametersPanel            matlab.ui.container.Panel
        DATA_LoadParametersButton       matlab.ui.control.Button
        TextArea_2                      matlab.ui.control.TextArea
        DATASHEETPanel                  matlab.ui.container.Panel
        DATA_OpenDataSheetButton        matlab.ui.control.Button
        DATA_OpenCurveFitScript         matlab.ui.control.Button
        ModellingTab                    matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        FINISHEDTab                     matlab.ui.container.Tab
        FINISHED_Knob                   matlab.ui.control.DiscreteKnob
        TextArea_3                      matlab.ui.control.TextArea
        MathTab                         matlab.ui.container.Tab
        OpentheDerivationButton         matlab.ui.control.Button
        TextArea_4                      matlab.ui.control.TextArea
        SimulinkTab                     matlab.ui.container.Tab
        SIMULINK_Knob                   matlab.ui.control.DiscreteKnob
        TextArea_5                      matlab.ui.control.TextArea
        SimscapeElectronicsTab          matlab.ui.container.Tab
        SIM_ELECTRONICS_Knob            matlab.ui.control.DiscreteKnob
        TextArea_6                      matlab.ui.control.TextArea
        SimscapeTab                     matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        MechanicalTab                   matlab.ui.container.Tab
        MECH_SSC_Knob                   matlab.ui.control.DiscreteKnob
        TextArea_7                      matlab.ui.control.TextArea
        ElectricalTab                   matlab.ui.container.Tab
        ELEC_SSC_Knob                   matlab.ui.control.DiscreteKnob
        TextArea_8                      matlab.ui.control.TextArea
        CombinedTab                     matlab.ui.container.Tab
        COMBINED_SSC_Knob               matlab.ui.control.DiscreteKnob
        TextArea_9                      matlab.ui.control.TextArea
        UTILITIESTab                    matlab.ui.container.Tab
        MATLABFunctionsPanel            matlab.ui.container.Panel
        FunctionListBox                 matlab.ui.control.ListBox
        OpenFileButton_MATLABFunctions  matlab.ui.control.Button
        SimscapeFunctionsPanel          matlab.ui.container.Panel
        SimscapeFuncs_ListBox           matlab.ui.control.ListBox
        OpenFileButton_SimscapeFunctions  matlab.ui.control.Button
        MATLABClassesPanel              matlab.ui.container.Panel
        ClassesListBox                  matlab.ui.control.ListBox
        OpenFileButton_MATLABClasses    matlab.ui.control.Button
        MATLABScriptsPanel              matlab.ui.container.Panel
        ScriptListBox                   matlab.ui.control.ListBox
        OpenFileButton_MATLABScripts    matlab.ui.control.Button
        SYSTEM_CHECKTab                 matlab.ui.container.Tab
        PRODUCTSPanel                   matlab.ui.container.Panel
        DoyouhavetherequiredproductsLabel  matlab.ui.control.Label
        TextArea_10                     matlab.ui.control.TextArea
        SYS_CHECK_But                   matlab.ui.control.Button
        CurveFittingToolboxLabel        matlab.ui.control.Label
        LAMP_CFIT                       matlab.ui.control.Lamp
        SimscapeElectronicsLampLabel    matlab.ui.control.Label
        LAMP_SCEL                       matlab.ui.control.Lamp
        SimscapeLampLabel               matlab.ui.control.Label
        LAMP_SC                         matlab.ui.control.Lamp
        SimulinkLampLabel               matlab.ui.control.Label
        LAMP_SL                         matlab.ui.control.Lamp
        MATLABLampLabel                 matlab.ui.control.Label
        LAMP_ML                         matlab.ui.control.Lamp
    end


    properties (Access = private)
        OBJ_bh_DS % Description
    end

    methods (Access = public)
    
        function do_product_check(app)
            DEFAULT_COLOR = [255 191 0]/255; % orange
            app.LAMP_ML.Color   = DEFAULT_COLOR;            
            app.LAMP_SL.Color   = DEFAULT_COLOR;            
            app.LAMP_CFIT.Color = DEFAULT_COLOR;            
            app.LAMP_SC.Color   = DEFAULT_COLOR;           
            app.LAMP_SCEL.Color = DEFAULT_COLOR;            
            
            % just pause for a second
            pause(1);
            
            % what products do you have
            v_S          = ver;
            prod_list_CE = {v_S.Name}';
            %prod_list_CE([31, 63, 67]) = []; % for testing only !
            % update the lamps
            my_database_CE = ...
                {               
                  'MATLAB',                       'LAMP_ML';
                  'Curve Fitting Toolbox',        'LAMP_CFIT';
                  'Simulink',                     'LAMP_SL';
                  'Simscape',                     'LAMP_SC';
                  'Simscape Electronics',         'LAMP_SCEL';           
                  };
              NUM_LAMPS = size(my_database_CE,1);
              for kk=1:NUM_LAMPS
                  the_prod_str  = my_database_CE{kk,1};
                  the_field_str = my_database_CE{kk,2};
                  tf_i_have_prod = strcmp(prod_list_CE, the_prod_str);
                  
                  if(1==nnz(tf_i_have_prod))
                      app.(the_field_str).Color = 'green';
                  else
                      app.(the_field_str).Color = 'red';
                  end
                  
              end % for
        end % function do_product_check(app)    

        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.OBJ_bh_DS = bh_solenoid_demo_selector_CLS();
            
            % add a graphic to the HELLO tab sheet
            hax = app.HELLO_AX;
            I = imread('Solenoid_PIC_01.JPG');  
            %imshow(I,'Parent', hax);
            image(hax,I);
            axis(hax,'tight');
            hax.Visible = 'off';
            
           % set figure Tag
           app.SOLENOIDDEMOUIFigure.Tag = 'TAG_APP_FIGURE_SOLENOID';
        end

        % Button pushed function: DATA_LoadParametersButton
        function DATA_LoadParametersButtonPushed(app, event)
            app.OBJ_bh_DS.data_load_params();
        end

        % Button pushed function: DATA_OpenDataSheetButton
        function DATA_OpenDataSheetButtonPushed(app, event)
            app.OBJ_bh_DS.data_open_datasheet();
        end

        % Button pushed function: DATA_OpenCurveFitScript
        function DATA_OpenCurveFitScriptButtonPushed(app, event)
            app.OBJ_bh_DS.data_open_curvefit_script();
        end

        % Value changed function: FINISHED_Knob
        function FINISHED_KnobValueChanged(app, event)
            value = app.FINISHED_Knob.Value;
            app.OBJ_bh_DS.mod_finished_knob(value);
        end

        % Button pushed function: OpentheDerivationButton
        function OpentheDerivationButtonPushed(app, event)
            app.OBJ_bh_DS.mod_math_open_derivation();
        end

        % Value changed function: SIMULINK_Knob
        function SIMULINK_KnobValueChanged(app, event)
            value = app.SIMULINK_Knob.Value;
            app.OBJ_bh_DS.mod_simulink_knob(value);
        end

        % Value changed function: SIM_ELECTRONICS_Knob
        function SIM_ELECTRONICS_KnobValueChanged(app, event)
            value = app.SIM_ELECTRONICS_Knob.Value;
            app.OBJ_bh_DS.mod_simelectronics_knob(value);
        end

        % Value changed function: MECH_SSC_Knob
        function MECH_SSC_KnobValueChanged(app, event)
            value = app.MECH_SSC_Knob.Value;
            app.OBJ_bh_DS.mod_simscape_mech_knob(value);
        end

        % Value changed function: ELEC_SSC_Knob
        function ELEC_SSC_KnobValueChanged(app, event)
            value = app.ELEC_SSC_Knob.Value;
            app.OBJ_bh_DS.mod_simscape_elec_knob(value);
        end

        % Value changed function: COMBINED_SSC_Knob
        function COMBINED_SSC_KnobValueChanged(app, event)
            value = app.COMBINED_SSC_Knob.Value;
            app.OBJ_bh_DS.mod_simscape_compined_knob(value);
        end

        % Button pushed function: SYS_CHECK_But
        function SYS_CHECK_ButButtonPushed(app, event)
            app.do_product_check();
        end

        % Button pushed function: OpenFileButton_MATLABFunctions
        function OpenFileButton_MATLABFunctionsPushed(app, event)
            value = app.FunctionListBox.Value;
            app.OBJ_bh_DS.utils_open_file(value);
        end

        % Button pushed function: OpenFileButton_MATLABScripts
        function OpenFileButton_MATLABScriptsPushed(app, event)
            value = app.ScriptListBox.Value;
            app.OBJ_bh_DS.utils_open_file(value);            
        end

        % Button pushed function: OpenFileButton_MATLABClasses
        function OpenFileButton_MATLABClassesPushed(app, event)
            value = app.ClassesListBox.Value;
            app.OBJ_bh_DS.utils_open_file(value);                      
        end

        % Button pushed function: OpenFileButton_SimscapeFunctions
        function OpenFileButton_SimscapeFunctionsPushed(app, event)
            value = app.SimscapeFuncs_ListBox.Value;
            app.OBJ_bh_DS.utils_open_file(value);                
        end

        % Button pushed function: OpenPresentationButton
        function OpenPresentationButtonPushed(app, event)
            app.OBJ_bh_DS.hello_open_pres();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SOLENOIDDEMOUIFigure = uifigure;
            app.SOLENOIDDEMOUIFigure.Name = 'SOLENOID DEMO';
            app.SolenoidModellingDEMOselectorLabel = uilabel(app.SOLENOIDDEMOUIFigure);
            app.SolenoidModellingDEMOselectorLabel.Text = 'Solenoid Modelling DEMO selector';
            app.TabGroup = uitabgroup(app.SOLENOIDDEMOUIFigure);
            app.HELLOTab = uitab(app.TabGroup);
            app.HELLOTab.Title = 'HELLO';
            app.TextArea = uitextarea(app.HELLOTab);
            app.TextArea.Value = {'In this demo we''re going to develop a model of a Solenoid - a well known translational actuator.'; ''; 'Specifically we''ll look at the following tasks:'; ''; '1.) We''ll derive the foundation maths that describes the system'; '2.) We''ll create a model in Simulink'; '3.) We''ll create a model in Simscape'; '4.) We''ll create a model in Simscape Electronics'; ''; 'And then we''ll compare the different modelling approaches.'};
            app.HelloandwelcomeLabel = uilabel(app.HELLOTab);
            app.HelloandwelcomeLabel.Text = 'Hello and welcome.';
            app.HELLO_AX = uiaxes(app.HELLOTab);
            app.OpenPresentationButton = uibutton(app.HELLOTab, 'push');
            app.OpenPresentationButton.ButtonPushedFcn = createCallbackFcn(app, @OpenPresentationButtonPushed, true);
            app.OpenPresentationButton.Text = 'Open Presentation';
            app.DATATab = uitab(app.TabGroup);
            app.DATATab.Title = 'DATA';
            app.ModelParametersPanel = uipanel(app.DATATab);
            app.ModelParametersPanel.Title = 'Model Parameters';
            app.DATA_LoadParametersButton = uibutton(app.ModelParametersPanel, 'push');
            app.DATA_LoadParametersButton.ButtonPushedFcn = createCallbackFcn(app, @DATA_LoadParametersButtonPushed, true);
            app.DATA_LoadParametersButton.Text = 'Load Parameters';
            app.TextArea_2 = uitextarea(app.ModelParametersPanel);
            app.TextArea_2.Value = {'Load parameters into the base MATLAB workspace'};
            app.DATASHEETPanel = uipanel(app.DATATab);
            app.DATASHEETPanel.Title = 'DATASHEET';
            app.DATA_OpenDataSheetButton = uibutton(app.DATASHEETPanel, 'push');
            app.DATA_OpenDataSheetButton.ButtonPushedFcn = createCallbackFcn(app, @DATA_OpenDataSheetButtonPushed, true);
            app.DATA_OpenDataSheetButton.Text = 'Open Datasheet';
            app.DATA_OpenCurveFitScript = uibutton(app.DATASHEETPanel, 'push');
            app.DATA_OpenCurveFitScript.ButtonPushedFcn = createCallbackFcn(app, @DATA_OpenCurveFitScriptButtonPushed, true);
            app.DATA_OpenCurveFitScript.Text = 'Open Curve Fit script';
            app.ModellingTab = uitab(app.TabGroup);
            app.ModellingTab.Title = 'Modelling';
            app.TabGroup2 = uitabgroup(app.ModellingTab);
            app.FINISHEDTab = uitab(app.TabGroup2);
            app.FINISHEDTab.Title = 'FINISHED';
            app.FINISHED_Knob = uiknob(app.FINISHEDTab, 'discrete');
            app.FINISHED_Knob.Items = {'Nothing', 'Simulink', 'Simscape Electronics', 'Simscape', 'Compare ALL'};
            app.FINISHED_Knob.ValueChangedFcn = createCallbackFcn(app, @FINISHED_KnobValueChanged, true);
            app.FINISHED_Knob.Value = 'Nothing';
            app.TextArea_3 = uitextarea(app.FINISHEDTab);
            app.TextArea_3.Value = {'These are the FINISHED models'};
            app.MathTab = uitab(app.TabGroup2);
            app.MathTab.Title = 'Math';
            app.OpentheDerivationButton = uibutton(app.MathTab, 'push');
            app.OpentheDerivationButton.ButtonPushedFcn = createCallbackFcn(app, @OpentheDerivationButtonPushed, true);
            app.OpentheDerivationButton.Text = 'Open the Derivation';
            app.TextArea_4 = uitextarea(app.MathTab);
            app.TextArea_4.Value = {'Our Hand derivation of the underlying physics for the solenoid.  We look specifically at the:'; ''; '1.) Magnetics'; '2.) Mechanics'; '3.) Electrics'};
            app.SimulinkTab = uitab(app.TabGroup2);
            app.SimulinkTab.Title = 'Simulink';
            app.SIMULINK_Knob = uiknob(app.SimulinkTab, 'discrete');
            app.SIMULINK_Knob.Items = {'Nothing', 'BEGIN', 'step_01', 'step_02', 'step_03', 'step_04', 'FINISHED'};
            app.SIMULINK_Knob.ValueChangedFcn = createCallbackFcn(app, @SIMULINK_KnobValueChanged, true);
            app.SIMULINK_Knob.Value = 'Nothing';
            app.TextArea_5 = uitextarea(app.SimulinkTab);
            app.TextArea_5.Value = {'The completed model AFTER each step can be opened.'};
            app.SimscapeElectronicsTab = uitab(app.TabGroup2);
            app.SimscapeElectronicsTab.Title = 'Simscape Electronics';
            app.SIM_ELECTRONICS_Knob = uiknob(app.SimscapeElectronicsTab, 'discrete');
            app.SIM_ELECTRONICS_Knob.Items = {'Nothing', 'BEGIN', 'step_01', 'step_02', 'FINISHED'};
            app.SIM_ELECTRONICS_Knob.ValueChangedFcn = createCallbackFcn(app, @SIM_ELECTRONICS_KnobValueChanged, true);
            app.SIM_ELECTRONICS_Knob.Value = 'Nothing';
            app.TextArea_6 = uitextarea(app.SimscapeElectronicsTab);
            app.TextArea_6.Value = {'The completed model AFTER each step can be opened.'};
            app.SimscapeTab = uitab(app.TabGroup2);
            app.SimscapeTab.Title = 'Simscape';
            app.TabGroup3 = uitabgroup(app.SimscapeTab);
            app.MechanicalTab = uitab(app.TabGroup3);
            app.MechanicalTab.Title = 'Mechanical';
            app.MECH_SSC_Knob = uiknob(app.MechanicalTab, 'discrete');
            app.MECH_SSC_Knob.Items = {'Nothing', 'BEGIN', 'step_01', 'step_02', 'step_03', 'FINISHED'};
            app.MECH_SSC_Knob.ValueChangedFcn = createCallbackFcn(app, @MECH_SSC_KnobValueChanged, true);
            app.MECH_SSC_Knob.Value = 'Nothing';
            app.TextArea_7 = uitextarea(app.MechanicalTab);
            app.TextArea_7.Value = {'The completed model AFTER each step can be opened.'};
            app.ElectricalTab = uitab(app.TabGroup3);
            app.ElectricalTab.Title = 'Electrical';
            app.ELEC_SSC_Knob = uiknob(app.ElectricalTab, 'discrete');
            app.ELEC_SSC_Knob.Items = {'Nothing', 'BEGIN', 'step_01', 'step_02', 'step_03', 'FINISHED'};
            app.ELEC_SSC_Knob.ValueChangedFcn = createCallbackFcn(app, @ELEC_SSC_KnobValueChanged, true);
            app.ELEC_SSC_Knob.Value = 'Nothing';
            app.TextArea_8 = uitextarea(app.ElectricalTab);
            app.TextArea_8.Value = {'The completed model AFTER each step can be opened.'};
            app.CombinedTab = uitab(app.TabGroup3);
            app.CombinedTab.Title = 'Combined';
            app.COMBINED_SSC_Knob = uiknob(app.CombinedTab, 'discrete');
            app.COMBINED_SSC_Knob.Items = {'Nothing', 'BEGIN', 'step_01', 'FINISHED'};
            app.COMBINED_SSC_Knob.ValueChangedFcn = createCallbackFcn(app, @COMBINED_SSC_KnobValueChanged, true);
            app.COMBINED_SSC_Knob.Value = 'Nothing';
            app.TextArea_9 = uitextarea(app.CombinedTab);
            app.TextArea_9.Value = {'The completed model AFTER each step can be opened.'};
            app.UTILITIESTab = uitab(app.TabGroup);
            app.UTILITIESTab.Title = 'UTILITIES';
            app.MATLABFunctionsPanel = uipanel(app.UTILITIESTab);
            app.MATLABFunctionsPanel.Title = 'MATLAB Functions';
            app.FunctionListBox = uilistbox(app.MATLABFunctionsPanel);
            app.FunctionListBox.Items = {'bh_func_calc_my_L.m', 'bh_func_calc_my_F.m', 'bh_func_calc_my_dLdy.m'};
            app.FunctionListBox.Value = 'bh_func_calc_my_L.m';
            app.OpenFileButton_MATLABFunctions = uibutton(app.MATLABFunctionsPanel, 'push');
            app.OpenFileButton_MATLABFunctions.ButtonPushedFcn = createCallbackFcn(app, @OpenFileButton_MATLABFunctionsPushed, true);
            app.OpenFileButton_MATLABFunctions.Text = 'Open File';
            app.SimscapeFunctionsPanel = uipanel(app.UTILITIESTab);
            app.SimscapeFunctionsPanel.Title = 'Simscape Functions';
            app.SimscapeFuncs_ListBox = uilistbox(app.SimscapeFunctionsPanel);
            app.SimscapeFuncs_ListBox.Items = {'bh_var_L.ssc', 'bh_Fmag.ssc'};
            app.SimscapeFuncs_ListBox.Value = 'bh_var_L.ssc';
            app.OpenFileButton_SimscapeFunctions = uibutton(app.SimscapeFunctionsPanel, 'push');
            app.OpenFileButton_SimscapeFunctions.ButtonPushedFcn = createCallbackFcn(app, @OpenFileButton_SimscapeFunctionsPushed, true);
            app.OpenFileButton_SimscapeFunctions.Text = 'Open File';
            app.MATLABClassesPanel = uipanel(app.UTILITIESTab);
            app.MATLABClassesPanel.Title = 'MATLAB Classes';
            app.ClassesListBox = uilistbox(app.MATLABClassesPanel);
            app.ClassesListBox.Items = {'bh_solenoid.m'};
            app.ClassesListBox.Value = 'bh_solenoid.m';
            app.OpenFileButton_MATLABClasses = uibutton(app.MATLABClassesPanel, 'push');
            app.OpenFileButton_MATLABClasses.ButtonPushedFcn = createCallbackFcn(app, @OpenFileButton_MATLABClassesPushed, true);
            app.OpenFileButton_MATLABClasses.Text = 'Open File';
            app.MATLABScriptsPanel = uipanel(app.UTILITIESTab);
            app.MATLABScriptsPanel.Title = 'MATLAB Scripts';
            app.ScriptListBox = uilistbox(app.MATLABScriptsPanel);
            app.ScriptListBox.Items = {'bh_model_params.m', 'bh_explore_Fvs_S_data.mlx'};
            app.ScriptListBox.Value = 'bh_model_params.m';
            app.OpenFileButton_MATLABScripts = uibutton(app.MATLABScriptsPanel, 'push');
            app.OpenFileButton_MATLABScripts.ButtonPushedFcn = createCallbackFcn(app, @OpenFileButton_MATLABScriptsPushed, true);
            app.OpenFileButton_MATLABScripts.Text = 'Open File';
            app.SYSTEM_CHECKTab = uitab(app.TabGroup);
            app.SYSTEM_CHECKTab.Title = 'SYSTEM_CHECK';
            app.PRODUCTSPanel = uipanel(app.SYSTEM_CHECKTab);
            app.PRODUCTSPanel.Title = 'PRODUCTS';
            app.DoyouhavetherequiredproductsLabel = uilabel(app.PRODUCTSPanel);
            app.DoyouhavetherequiredproductsLabel.Text = 'Do you have the required products ?';
            app.TextArea_10 = uitextarea(app.PRODUCTSPanel);
            app.TextArea_10.Value = {'If you see any RED lamps, you may not be able to run some of the demos.'};
            app.SYS_CHECK_But = uibutton(app.PRODUCTSPanel, 'push');
            app.SYS_CHECK_But.ButtonPushedFcn = createCallbackFcn(app, @SYS_CHECK_ButButtonPushed, true);
            app.SYS_CHECK_But.Text = 'CLICK ME to test your installation';
            app.CurveFittingToolboxLabel = uilabel(app.PRODUCTSPanel);
            app.CurveFittingToolboxLabel.Text = 'Curve Fitting Toolbox';
            app.LAMP_CFIT = uilamp(app.PRODUCTSPanel);
            app.SimscapeElectronicsLampLabel = uilabel(app.PRODUCTSPanel);
            app.SimscapeElectronicsLampLabel.Text = 'Simscape Electronics';
            app.LAMP_SCEL = uilamp(app.PRODUCTSPanel);
            app.SimscapeLampLabel = uilabel(app.PRODUCTSPanel);
            app.SimscapeLampLabel.Text = 'Simscape';
            app.LAMP_SC = uilamp(app.PRODUCTSPanel);
            app.SimulinkLampLabel = uilabel(app.PRODUCTSPanel);
            app.SimulinkLampLabel.Text = 'Simulink';
            app.LAMP_SL = uilamp(app.PRODUCTSPanel);
            app.MATLABLampLabel = uilabel(app.PRODUCTSPanel);
            app.MATLABLampLabel.Text = 'MATLAB';
            app.LAMP_ML = uilamp(app.PRODUCTSPanel);
        end
    end

    methods (Access = public)

        % Construct app
        function app = bh_solenoid_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SOLENOIDDEMOUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SOLENOIDDEMOUIFigure)
        end
    end
end