classdef bh_app_smd < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        TabGroup            matlab.ui.container.TabGroup
        Tab_2dof            matlab.ui.container.Tab
        UIAxes_2dof         matlab.ui.control.UIAxes
        TabGroup2_3         matlab.ui.container.TabGroup
        NumericTab_4        matlab.ui.container.Tab
        Knob_2dof_Numeric   matlab.ui.control.DiscreteKnob
        SymbolicTab_3       matlab.ui.container.Tab
        Knob_2dof_Symbolic  matlab.ui.control.DiscreteKnob
        SimulinkTab_3       matlab.ui.container.Tab
        Knob_2dof_Simulink  matlab.ui.control.DiscreteKnob
        LabTab              matlab.ui.container.Tab
        Knob_2dof_Lab       matlab.ui.control.DiscreteKnob
        Tab_1_dof           matlab.ui.container.Tab
        UIAxes_1dof         matlab.ui.control.UIAxes
        TabGroup2_2         matlab.ui.container.TabGroup
        NumericTab_2        matlab.ui.container.Tab
        Knob_1dof_Numeric   matlab.ui.control.DiscreteKnob
        SymbolicTab_2       matlab.ui.container.Tab
        Knob_1dof_Symbolic  matlab.ui.control.DiscreteKnob
        SimulinkTab_2       matlab.ui.container.Tab
        Knob_1dof_Simulink  matlab.ui.control.DiscreteKnob
        LABTab              matlab.ui.container.Tab
        Knob_1dof_Lab       matlab.ui.control.DiscreteKnob
    end


    properties (Access = private)
        DEMO_SELCT_OBJ bh_smd_demo_selector_CLS % Description
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % add images to the tab sheet axes
            hax = app.UIAxes_1dof;
            I = imread('bh_PIC_1dof.PNG');
            image(hax,I)
            axis(hax,'equal');
            hax.Visible = 'off';
            
            hax = app.UIAxes_2dof;
            I = imread('bh_PIC_2dof_system.PNG');
            image(hax,I)
            axis(hax,'equal');
            hax.Visible = 'off';
            
            % instantiate a demo selector object
            app.DEMO_SELCT_OBJ  = bh_smd_demo_selector_CLS();
        end

        % Value changed function: Knob_1dof_Lab
        function Knob_1dof_LabValueChanged(app, event)
            value = app.Knob_1dof_Lab.Value; % a CHAR array
            app.DEMO_SELCT_OBJ.DK_1dof_lab( value );  
        end

        % Value changed function: Knob_1dof_Numeric
        function Knob_1dof_NumericValueChanged(app, event)
            value = app.Knob_1dof_Numeric.Value; % a CHAR array
            app.DEMO_SELCT_OBJ.DK_1dof_numeric(value);
        end

        % Value changed function: Knob_1dof_Simulink
        function Knob_1dof_SimulinkValueChanged(app, event)
            value = app.Knob_1dof_Simulink.Value; % a CHAR array
            app.DEMO_SELCT_OBJ.DK_1dof_simulink(value);
        end

        % Value changed function: Knob_1dof_Symbolic
        function Knob_1dof_SymbolicValueChanged(app, event)
            value = app.Knob_1dof_Symbolic.Value; % a CHAR array
            app.DEMO_SELCT_OBJ.DK_1dof_symbolic(value);  
        end

        % Value changed function: Knob_2dof_Lab
        function Knob_2dof_LabValueChanged(app, event)
            value = app.Knob_2dof_Lab.Value;    % a CHAR array
            app.DEMO_SELCT_OBJ.DK_2dof_lab( value );          
        end

        % Value changed function: Knob_2dof_Numeric
        function Knob_2dof_NumericValueChanged(app, event)
            value = app.Knob_2dof_Numeric.Value;   % a CHAR array
            app.DEMO_SELCT_OBJ.DK_2dof_numeric(value);
        end

        % Value changed function: Knob_2dof_Simulink
        function Knob_2dof_SimulinkValueChanged(app, event)
            value = app.Knob_2dof_Simulink.Value;         % a CHAR array
            app.DEMO_SELCT_OBJ.DK_2dof_simulink(value);
        end

        % Value changed function: Knob_2dof_Symbolic
        function Knob_2dof_SymbolicValueChanged(app, event)
            value = app.Knob_2dof_Symbolic.Value;      % a CHAR array
            app.DEMO_SELCT_OBJ.DK_2dof_symbolic(value);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab_2dof = uitab(app.TabGroup);
            app.Tab_2dof.Title = '2-dof';
            app.UIAxes_2dof = uiaxes(app.Tab_2dof);
            title(app.UIAxes_2dof, 'Title')
            xlabel(app.UIAxes_2dof, 'X')
            ylabel(app.UIAxes_2dof, 'Y')
            app.TabGroup2_3 = uitabgroup(app.Tab_2dof);
            app.NumericTab_4 = uitab(app.TabGroup2_3);
            app.NumericTab_4.Title = 'Numeric';
            app.Knob_2dof_Numeric = uiknob(app.NumericTab_4, 'discrete');
            app.Knob_2dof_Numeric.Items = {'Nothing', 'Step_response'};
            app.Knob_2dof_Numeric.ValueChangedFcn = createCallbackFcn(app, @Knob_2dof_NumericValueChanged, true);
            app.Knob_2dof_Numeric.Value = 'Nothing';
            app.SymbolicTab_3 = uitab(app.TabGroup2_3);
            app.SymbolicTab_3.Title = 'Symbolic';
            app.Knob_2dof_Symbolic = uiknob(app.SymbolicTab_3, 'discrete');
            app.Knob_2dof_Symbolic.Items = {'Nothing', 'Homogeneous', 'Step_response', 'Block_creation'};
            app.Knob_2dof_Symbolic.ValueChangedFcn = createCallbackFcn(app, @Knob_2dof_SymbolicValueChanged, true);
            app.Knob_2dof_Symbolic.Value = 'Nothing';
            app.SimulinkTab_3 = uitab(app.TabGroup2_3);
            app.SimulinkTab_3.Title = 'Simulink';
            app.Knob_2dof_Simulink = uiknob(app.SimulinkTab_3, 'discrete');
            app.Knob_2dof_Simulink.Items = {'Nothing', 'Simulink', 'Simscape', 'Compare'};
            app.Knob_2dof_Simulink.ValueChangedFcn = createCallbackFcn(app, @Knob_2dof_SimulinkValueChanged, true);
            app.Knob_2dof_Simulink.Value = 'Nothing';
            app.LabTab = uitab(app.TabGroup2_3);
            app.LabTab.Title = 'Lab';
            app.Knob_2dof_Lab = uiknob(app.LabTab, 'discrete');
            app.Knob_2dof_Lab.Items = {'Nothing', 'Integration_concept', 'Simulink_model', 'Simscape_model'};
            app.Knob_2dof_Lab.ValueChangedFcn = createCallbackFcn(app, @Knob_2dof_LabValueChanged, true);
            app.Knob_2dof_Lab.Value = 'Nothing';
            app.Tab_1_dof = uitab(app.TabGroup);
            app.Tab_1_dof.Title = '1-dof';
            app.UIAxes_1dof = uiaxes(app.Tab_1_dof);
            title(app.UIAxes_1dof, 'Title')
            xlabel(app.UIAxes_1dof, 'X')
            ylabel(app.UIAxes_1dof, 'Y')
            app.TabGroup2_2 = uitabgroup(app.Tab_1_dof);
            app.NumericTab_2 = uitab(app.TabGroup2_2);
            app.NumericTab_2.Title = 'Numeric';
            app.Knob_1dof_Numeric = uiknob(app.NumericTab_2, 'discrete');
            app.Knob_1dof_Numeric.Items = {'Nothing', 'Step_response'};
            app.Knob_1dof_Numeric.ValueChangedFcn = createCallbackFcn(app, @Knob_1dof_NumericValueChanged, true);
            app.Knob_1dof_Numeric.Value = 'Nothing';
            app.SymbolicTab_2 = uitab(app.TabGroup2_2);
            app.SymbolicTab_2.Title = 'Symbolic';
            app.Knob_1dof_Symbolic = uiknob(app.SymbolicTab_2, 'discrete');
            app.Knob_1dof_Symbolic.Items = {'Nothing', 'Homog_and_Step'};
            app.Knob_1dof_Symbolic.ValueChangedFcn = createCallbackFcn(app, @Knob_1dof_SymbolicValueChanged, true);
            app.Knob_1dof_Symbolic.Value = 'Nothing';
            app.SimulinkTab_2 = uitab(app.TabGroup2_2);
            app.SimulinkTab_2.Title = 'Simulink';
            app.Knob_1dof_Simulink = uiknob(app.SimulinkTab_2, 'discrete');
            app.Knob_1dof_Simulink.Items = {'Nothing', '3_ways', 'Compare'};
            app.Knob_1dof_Simulink.ValueChangedFcn = createCallbackFcn(app, @Knob_1dof_SimulinkValueChanged, true);
            app.Knob_1dof_Simulink.Value = 'Nothing';
            app.LABTab = uitab(app.TabGroup2_2);
            app.LABTab.Title = 'LAB';
            app.Knob_1dof_Lab = uiknob(app.LABTab, 'discrete');
            app.Knob_1dof_Lab.Items = {'Nothing', 'Function_handles'};
            app.Knob_1dof_Lab.ValueChangedFcn = createCallbackFcn(app, @Knob_1dof_LabValueChanged, true);
            app.Knob_1dof_Lab.Value = 'Nothing';
        end
    end

    methods (Access = public)

        % Construct app
        function app = bh_app_smd

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end